classdef TestCalculatorV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagneticCircuitCalculatorUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GuideModeTab                    matlab.ui.container.Tab
        MagneticCircuitCharacteristicsPanel  matlab.ui.container.Panel
        CoilVariableDropDown            matlab.ui.control.DropDown
        CoilVariableDropDownLabel       matlab.ui.control.Label
        DesiredVariableDropDown         matlab.ui.control.DropDown
        DesiredVariableDropDownLabel    matlab.ui.control.Label
        CoreCharacteristicsPanel        matlab.ui.container.Panel
        SaturationImAEditField_2        matlab.ui.control.NumericEditField
        SaturationImAEditField_2Label   matlab.ui.control.Label
        CoreLengthRmmEditField_2        matlab.ui.control.NumericEditField
        CoreLengthRmmEditField_2Label   matlab.ui.control.Label
        SaturationBmTEditField          matlab.ui.control.NumericEditField
        SaturationBmTEditFieldLabel     matlab.ui.control.Label
        SaturationHAmEditField          matlab.ui.control.NumericEditField
        SaturationHAmEditFieldLabel     matlab.ui.control.Label
        DesiredCurrentmAEditField       matlab.ui.control.NumericEditField
        DesiredCurrentmAEditFieldLabel  matlab.ui.control.Label
        DesiredBFieldmTEditField        matlab.ui.control.NumericEditField
        DesiredBFieldmTEditFieldLabel   matlab.ui.control.Label
        GapLenghtmmEditField            matlab.ui.control.NumericEditField
        GapLenghtmmEditFieldLabel       matlab.ui.control.Label
        CoreLengthYmmEditField_2        matlab.ui.control.NumericEditField
        CoreLengthYmmEditField_2Label   matlab.ui.control.Label
        GapWidthmmEditField             matlab.ui.control.NumericEditField
        GapWidthmmEditFieldLabel        matlab.ui.control.Label
        CoreLengthBmmEditField_2        matlab.ui.control.NumericEditField
        CoreLengthBmmEditField_2Label   matlab.ui.control.Label
        CoreThicknessmmEditField        matlab.ui.control.NumericEditField
        CoreThicknessmmEditFieldLabel   matlab.ui.control.Label
        CoreWidthmmEditField            matlab.ui.control.NumericEditField
        CoreWidthmmEditFieldLabel       matlab.ui.control.Label
        CorePermeabilityrEditField      matlab.ui.control.NumericEditField
        CorePermeabilityrEditFieldLabel  matlab.ui.control.Label
        CoreMaterialDropDown            matlab.ui.control.DropDown
        CoreMaterialDropDownLabel       matlab.ui.control.Label
        LoadCoilButton                  matlab.ui.control.Button
        LoadDesignButton                matlab.ui.control.Button
        CoilCharacteristicsPanel        matlab.ui.container.Panel
        SourceCurrentmAEditField        matlab.ui.control.NumericEditField
        SourceCurrentmAEditFieldLabel   matlab.ui.control.Label
        ResistanceEditField             matlab.ui.control.NumericEditField
        ResistanceLabel                 matlab.ui.control.Label
        WireDiametermmEditField         matlab.ui.control.NumericEditField
        WireDiametermmEditFieldLabel    matlab.ui.control.Label
        MaximumCurrentAEditField        matlab.ui.control.NumericEditField
        MaximumCurrentAEditFieldLabel   matlab.ui.control.Label
        HFieldAmEditField               matlab.ui.control.NumericEditField
        HFieldAmLabel                   matlab.ui.control.Label
        TurnsEditField                  matlab.ui.control.NumericEditField
        TurnsEditFieldLabel             matlab.ui.control.Label
        AWGDropDown                     matlab.ui.control.DropDown
        AWGDropDownLabel                matlab.ui.control.Label
        CoilLengthmmEditField           matlab.ui.control.NumericEditField
        CoilLengthmmEditFieldLabel      matlab.ui.control.Label
        OutterRadiusmmEditField         matlab.ui.control.NumericEditField
        OutterRadiusmmEditFieldLabel    matlab.ui.control.Label
        InnerRadiusmmEditField          matlab.ui.control.NumericEditField
        InnerRadiusmmEditFieldLabel     matlab.ui.control.Label
        CoreCurves                      matlab.ui.control.UIAxes
        CoreGuide                       matlab.ui.control.UIAxes
        CircuitResponsePlot             matlab.ui.control.UIAxes
        GapDistPlot                     matlab.ui.control.UIAxes
        CalculatorTab                   matlab.ui.container.Tab
        MagneticCircuitCharacteristicsPanel_2  matlab.ui.container.Panel
        CoilVariableDropDown_2          matlab.ui.control.DropDown
        CoilVariableDropDown_2Label     matlab.ui.control.Label
        DesiredVariableDropDown_2       matlab.ui.control.DropDown
        DesiredVariableDropDown_2Label  matlab.ui.control.Label
        CoreCharacteristicsPanel_2      matlab.ui.container.Panel
        SaturationImAEditField          matlab.ui.control.NumericEditField
        SaturationImAEditFieldLabel     matlab.ui.control.Label
        CoreLengthRmmEditField          matlab.ui.control.NumericEditField
        CoreLengthRmmEditFieldLabel     matlab.ui.control.Label
        SaturationBmTEditField_2        matlab.ui.control.NumericEditField
        SaturationBmTEditField_2Label   matlab.ui.control.Label
        SaturationHAmEditField_2        matlab.ui.control.NumericEditField
        SaturationHAmEditField_2Label   matlab.ui.control.Label
        DesiredCurrentmAEditField_2     matlab.ui.control.NumericEditField
        DesiredCurrentmAEditField_2Label  matlab.ui.control.Label
        DesiredBFieldmTEditField_2      matlab.ui.control.NumericEditField
        DesiredBFieldmTEditField_2Label  matlab.ui.control.Label
        GapLengthmmEditField            matlab.ui.control.NumericEditField
        GapLengthmmEditFieldLabel       matlab.ui.control.Label
        CoreLengthYmmEditField          matlab.ui.control.NumericEditField
        CoreLengthYmmLabel              matlab.ui.control.Label
        GapWidthmmEditField_2           matlab.ui.control.NumericEditField
        GapWidthmmEditField_2Label      matlab.ui.control.Label
        CoreLengthBmmEditField          matlab.ui.control.NumericEditField
        CoreLengthBmmEditFieldLabel     matlab.ui.control.Label
        CoreThicknessmmEditField_2      matlab.ui.control.NumericEditField
        CoreThicknessmmEditField_2Label  matlab.ui.control.Label
        CoreWidthmmEditField_2          matlab.ui.control.NumericEditField
        CoreWidthmmEditField_2Label     matlab.ui.control.Label
        CorePermeabilityrEditField_2    matlab.ui.control.NumericEditField
        CorePermeabilityrEditField_2Label  matlab.ui.control.Label
        CoreMaterialDropDown_2          matlab.ui.control.DropDown
        CoreMaterialDropDown_2Label     matlab.ui.control.Label
        LoadCoilButton_2                matlab.ui.control.Button
        LoadDesignButton_2              matlab.ui.control.Button
        CoilCharacteristicsPanel_2      matlab.ui.container.Panel
        SourceCurrentmAEditField_2      matlab.ui.control.NumericEditField
        SourceCurrentmAEditField_2Label  matlab.ui.control.Label
        ResistanceEditField_2           matlab.ui.control.NumericEditField
        ResistanceEditField_2Label      matlab.ui.control.Label
        WireDiametermmEditField_2       matlab.ui.control.NumericEditField
        WireDiametermmEditField_2Label  matlab.ui.control.Label
        MaximumCurrentAEditField_2      matlab.ui.control.NumericEditField
        MaximumCurrentAEditField_2Label  matlab.ui.control.Label
        HFieldAmEditField_2             matlab.ui.control.NumericEditField
        HFieldAmEditField_2Label        matlab.ui.control.Label
        TurnsEditField_2                matlab.ui.control.NumericEditField
        TurnsEditField_2Label           matlab.ui.control.Label
        AWGDropDown_2                   matlab.ui.control.DropDown
        AWGDropDown_2Label              matlab.ui.control.Label
        CoilLengthmmEditField_2         matlab.ui.control.NumericEditField
        CoilLengthmmEditField_2Label    matlab.ui.control.Label
        OutterRadiusmmEditField_2       matlab.ui.control.NumericEditField
        OutterRadiusmmEditField_2Label  matlab.ui.control.Label
        InnerRadiusmmEditField_2        matlab.ui.control.NumericEditField
        InnerRadiusmmEditField_2Label   matlab.ui.control.Label
        CoreCurves_2                    matlab.ui.control.UIAxes
        CoreGuide_2                     matlab.ui.control.UIAxes
        CircuitResponsePlot_2           matlab.ui.control.UIAxes
        GapDistPlot_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Property % Description
        AWG_diameter = [11.68400,10.40380,9.26592,8.25246,7.34822,6.54304,5.82676,5.18922,4.62026,4.11480,3.66522,3.26390,2.90576,2.58826,2.30378,2.05232,1.82880,1.62814,1.45034,1.29032,1.15062,1.02362,0.91186,0.81280,0.72390,0.64516,0.57404,0.51054,0.45466,0.40386,0.36068,0.32004,0.28702,0.25400,0.22606,0.20320,0.20000,0.18034,0.18000,0.16002,0.16002,0.14224,0.14000,0.12700,0.12500,0.11430,0.11200,0.10160,0.10000,0.08890,0.07874];
        AWG_CurrentLimit = [302,239,190,150,119,94,75,60,47,37,30,24,19,15,12,9.3,7.4,5.9,4.7,3.7,2.9,2.3,1.8,1.5,1.2,0.92,0.729,0.577,0.457,0.361,0.288,0.226,0.182,0.142,0.113,0.091,0.088,0.072,0.072,0.056,0.056,0.044,0.043,0.035,0.034,0.0289,0.0277,0.0228,0.0225,0.0175,0.0137]; 
        AWG_Ohms = [0.16072,0.202704,0.255512,0.322424,0.406392,0.512664,0.64616,0.81508,1.027624,1.295928,1.634096,2.060496,2.598088,3.276392,4.1328,5.20864,6.56984,8.282,10.44352,13.17248,16.60992,20.9428,26.40728,33.292,41.984,52.9392,66.7808,84.1976,106.1736,133.8568,168.8216,212.872,268.4024,338.496,426.728,538.248,555.61,678.632,680.55,855.752,855.752,1079.12,1114,1360,1404,1715,1750,2163,2198,2728,3440]/1000;
        Core_Permeability = [1000,250000,18000,350000,350000];
        Core_Coercivity = [1,1.5,35,1,1.5];
        Core_Saturation_induction = [1,0.8,2.35,0.74,0.74]*1000;
        mumetal_Am=[0.333215041040991;1.16427928548295;1.99974644797483;2.84103080321419;3.67267482524059;4.51715636455425;5.34979542328486;6.18319436729126;7.02416646053227;7.85586825580301];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
        mumetal_T=[0.00225793394945231;0.0309927189805654;0.0611349421407720;0.0913357865259480;0.120960003584199;0.150779674435323;0.180970757654454;0.211464830179053;0.241001074728324;0.270918492811648];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
        vp100_Am=[0.321918525574721;1.15178013160470;1.98582261862349;2.83125741646880;3.66324425824262;4.50057655751259;5.33949396043829;6.21501318055649;7.05238487067488;7.88984417558944];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
        vp100_T=[0.00651789013303982;0.0217039276763562;0.0374416279319943;0.0538535819682542;0.0701276207161250;0.0865702236176809;0.103380598732196;0.120614936708839;0.137190349234422;0.153750436264562];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
        vf48_Am=[0;0.0397336121637026;0.0794672243274052;0.119200836491108;0.158934448654810;0.198668060818513;0.238401672982215;0.278135285145918;0.317868897309621;0.357602509473323];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
        vf48_T=[0;0.00283103152812874;0.00566206305625747;0.00849309458438621;0.0113241261125149;0.0141551576406437;0.0169861891687724;0.0198172206969011;0.0226482522250299;0.0254792837531586];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
        uv80_Am=[0.323517300640056;1.15484903679487;1.98933015483682;2.83387639063485;3.66590405565157;4.50442420783931;5.33968510552494;6.17822714151736;7.01503184215098;7.85200739561602];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
        uv80_T=[0.00715996399222275;0.0354748809149210;0.0640088834097838;0.0925165927185737;0.121182036832025;0.149663452954742;0.178022191854228;0.206617532960706;0.235028846076450;0.263264883440930];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
        Core_Am=[[0.333215041040991;1.16427928548295;1.99974644797483;2.84103080321419;3.67267482524059;4.51715636455425;5.34979542328486;6.18319436729126;7.02416646053227;7.85586825580301]';[0.321918525574721;1.15178013160470;1.98582261862349;2.83125741646880;3.66324425824262;4.50057655751259;5.33949396043829;6.21501318055649;7.05238487067488;7.88984417558944]';[0;0.0397336121637026;0.0794672243274052;0.119200836491108;0.158934448654810;0.198668060818513;0.238401672982215;0.278135285145918;0.317868897309621;0.357602509473323]';[0.323517300640056;1.15484903679487;1.98933015483682;2.83387639063485;3.66590405565157;4.50442420783931;5.33968510552494;6.17822714151736;7.01503184215098;7.85200739561602]']';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
        Core_T=[[0.00225793394945231;0.0309927189805654;0.0611349421407720;0.0913357865259480;0.120960003584199;0.150779674435323;0.180970757654454;0.211464830179053;0.241001074728324;0.270918492811648]';[0.00651789013303982;0.0217039276763562;0.0374416279319943;0.0538535819682542;0.0701276207161250;0.0865702236176809;0.103380598732196;0.120614936708839;0.137190349234422;0.153750436264562]';[0;0.00283103152812874;0.00566206305625747;0.00849309458438621;0.0113241261125149;0.0141551576406437;0.0169861891687724;0.0198172206969011;0.0226482522250299;0.0254792837531586]';[0.00715996399222275;0.0354748809149210;0.0640088834097838;0.0925165927185737;0.121182036832025;0.149663452954742;0.178022191854228;0.206617532960706;0.235028846076450;0.263264883440930]']';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
        Core_label=["Mumetal","Vacoperm 100","Vacoflux 48","Ultravac 80"];
    end
    
    
    methods (Access = public)
        
        function [Out] = MagCirc_Final(~,mode,X,LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,LGap,mu_r)
            mu_0 = 1.257e-6;
            %INPUT VARIABLES_________________
            %mode determines the main input and output of the function by 0 or 1
            %X_0 is source current
            %X_1 is Bfield on gap
            %LCoil is the length of the coil
            %R1coil and R2coil are the inner and outter radius of the coil
            %nTurns is the number of turns on the coil
            %Thcore is the thickness of the core and gap
            %WdCore, WdGap are the widths of the core section and gap section (coregap section has the same width as the gap section)
            %LCore1, LCore2, LCoreG, LGap are the lengths of the core, coregap and gap sections
            %mu_r is the relative permeability of the core material
            %INTERNAL VARIABLES______________
            %ACore, AGap (=ACoreG) is the cross-sectional area of the core, coregap and gap sections
            ACore = ThCore*WdCore;
            ACoreG = ThCore*WdGap;
            AGap = ACoreG;
            %RCore, RCoreG, RGap, and REqv are the reluctance of the core, coregap, gap and equivalent of the whole circuit
            RCore = (LCore1+(LCore2*2))/(mu_0*mu_r*ACore);
            RCoreG = (LCoreG*2)/(mu_0*mu_r*ACoreG);
            RGap = (LGap)/(mu_0*AGap);
            REqv = RCore + RCoreG + RGap;
            %HCoil is the magnetic field for thick coils
            %VmCoil is the magnetomotive force of the coil
            
            %OUTPUT VARIABLES________________
            %Out0 is Bfield on gap
            %Out1 is source current
            if (mode == 0)%X is current i and out is BField
                lnCoil = log(((2*R2Coil)+sqrt((4*R2Coil^2)+(LCoil^2)))/((2*R1Coil)+sqrt((4*R1Coil^2)+(LCoil^2))));
                HCoil = (nTurns*X*lnCoil)/(2*(R2Coil-R1Coil));
                VmCoil = HCoil*LCoil;
                
                Out = (VmCoil)/(AGap*REqv); %B
            elseif (mode == 1)%X is BField and Out is current i
                lnCoil = log(((2*R2Coil)+sqrt((4*R2Coil^2)+(LCoil^2)))/((2*R1Coil)+sqrt((4*R1Coil^2)+(LCoil^2))));
                altHCoil = (nTurns*lnCoil)/(2*(R2Coil-R1Coil));
                
                Out = (X*AGap*REqv)/(LCoil*altHCoil); %i
            else
                fprintf('1 finds B field \n 0 finds current i \n')
            end  
        end

        function OutMCF = MagCirc_Furlani(~,mode,X,mu_r,n,Ag,Ac,lg,lc)%app
            mu_0 = 1.257e-6;
            if (mode == 0) 
                %B
                OutMCF = (X*mu_0*n)/((Ag/Ac)*(mu_0/mu_r)*lc+lg); %B
            elseif (mode == 1)
                %i
                OutMCF = X*((Ag/Ac)*(mu_0/mu_r)*lc+lg)/(mu_0*n); %i
            else
                fprintf('1 finds B field \n 0 finds current i \n')
            end
        end
        
        function OutTCF = ThickCoil_Fiorillo(~,mode,X,n,L,R1,R2)
            mu_0 = 1.257e-6;
            if (mode == 0)
                %H
                natL = log(((2*R2)+sqrt((4*(R2^2))+(L^2)))/((2*R1)+sqrt((4*(R1^2))+(L^2))));
                OutTCF = (n*X*natL)/(2*(R2-R1));
            elseif (mode == 1)
                %i
                natL = log(((2*R2)+sqrt((4*(R2^2))+(L^2)))/((2*R1)+sqrt((4*(R1^2))+(L^2))));
                OutTCF = (X*2*(R2-R1))/(natL*n);
            else
                fprintf('1 finds H field \n 0 finds current i \n')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rectangle(app.CoreGuide,'Position',[0 0 90 10],'Linewidth',1,'EdgeColor','r','Facecolor','r'); %longitud nucleo
            rectangle(app.CoreGuide,'Position',[0 50 90 10],'Linewidth',1,'EdgeColor','r','Facecolor','r'); %longitud nucleo
            rectangle(app.CoreGuide,'Position',[0 10 10 40],'Linewidth',1,'EdgeColor','b','Facecolor','b'); %nucleo
            rectangle(app.CoreGuide,'Position',[90 0 10 25],'Linewidth',1,'EdgeColor','y','Facecolor','y'); %longitud gap
            rectangle(app.CoreGuide,'Position',[90 35 10 25],'Linewidth',1,'EdgeColor','y','Facecolor','y'); %longitud gap
            title(app.CoreGuide,'Electromagnet C type core');
            xlabel(app.CoreGuide,'Example length [mm]');
            ylabel(app.CoreGuide,'Example width [mm]');
            
            rectangle(app.CoreGuide_2,'Position',[0 0 90 10],'Linewidth',1,'EdgeColor','r','Facecolor','r'); %longitud nucleo
            rectangle(app.CoreGuide_2,'Position',[0 50 90 10],'Linewidth',1,'EdgeColor','r','Facecolor','r'); %longitud nucleo
            rectangle(app.CoreGuide_2,'Position',[0 10 10 40],'Linewidth',1,'EdgeColor','b','Facecolor','b'); %nucleo
            rectangle(app.CoreGuide_2,'Position',[90 0 10 25],'Linewidth',1,'EdgeColor','y','Facecolor','y'); %longitud gap
            rectangle(app.CoreGuide_2,'Position',[90 35 10 25],'Linewidth',1,'EdgeColor','y','Facecolor','y'); %longitud gap
            title(app.CoreGuide_2,'Electromagnet C type core');
            xlabel(app.CoreGuide_2,'Example length [mm]');
            ylabel(app.CoreGuide_2,'Example width [mm]');
            
            plot(app.CoreCurves,app.Core_Am,app.Core_T)
            lgc1 = legend(app.CoreCurves,app.Core_label);
            lgc1.Location = 'southeast';
            
            plot(app.CoreCurves_2,app.Core_Am,app.Core_T)
            lgc2 = legend(app.CoreCurves_2,app.Core_label);
            lgc2.Location = 'southeast';
        end

        % Button pushed function: LoadDesignButton
        function LoadDesignButtonPushed(app, event)
            %Fiorillo ThickCoil
            %mode = str2double(app.CoilVariableDropDown.Value);
            R2 = (app.OutterRadiusmmEditField.Value)/1000;
            R1 = (app.InnerRadiusmmEditField.Value)/1000;
            L = (app.CoilLengthmmEditField.Value)/1000;
            %n = app.TurnsEditField.Value;
            %Furlani Mag Circuit
            iF = app.DesiredCurrentmAEditField.Value/1000;
            lg = app.GapLenghtmmEditField.Value/1000;
            mu_r = app.CorePermeabilityrEditField.Value;
            n = app.TurnsEditField.Value;
            lc = app.CoreLengthBmmEditField_2.Value/1000;
            wc = app.CoreWidthmmEditField.Value/1000;
            wg = app.GapWidthmmEditField.Value/1000;
            tc = app.CoreThicknessmmEditField.Value/1000;
            tg = app.CoreLengthYmmEditField_2.Value/1000;
            Ag = wg*tg;
            Ac = wc*tc;
            %mu_0 = 1.25e-6;
            
            gapVect = (lg/2:lg/2:2*lg);
            iVect = (-iF:iF/9:iF);
            iLegend = zeros(length(iVect),1);
            BVect = zeros(length(iVect),length(gapVect));
            aa = [' [mm]';' [mm]';' [mm]';' [mm]'];
            for j = 1:length(gapVect)
                iLegend(j)= int2str(gapVect(j));
                for i = 1:length(iVect)
                    %BVect(i,j) = (iVect(i)*mu_0*n)/((Ag/Ac)*(mu_0/mu_r)*lc+gapVect(j));
                    HFio = ThickCoil_Fiorillo(app,0,iVect(i),n,L,R1,R2);
                    if (HFio >= 100 || HFio <= -100)
                        BVect(i,j) = MagCirc_Furlani(app,0,sign(HFio)*100*L,mu_r,n,Ag,Ac,gapVect(j),lc);
                    else
                        BVect(i,j) = MagCirc_Furlani(app,0,HFio*L,mu_r,n,Ag,Ac,gapVect(j),lc);
                    end
                end
            end
            plot(app.CircuitResponsePlot,iVect*1000,BVect*1000);
            title(app.CircuitResponsePlot,'B-Field, Air Gap Length & Source Current');
            lg1 = legend(app.CircuitResponsePlot,[num2str((gapVect*1000)'),aa]); 
            lg1.Location = 'southeast';
            xlabel(app.CircuitResponsePlot,'Source Current [mA]');
            ylabel(app.CircuitResponsePlot,'Magnetic Flux Density B [mT]');
            
            gapVect2 = (lg/10:lg/10:lg*4);
            iVect2 = (iF/4:iF/4:iF);
            iLegend = zeros(length(iVect2),1);
            BVect2 = zeros(length(gapVect2),length(iVect2));
            aa2 = [' [mA]';' [mA]';' [mA]';' [mA]'];
            for j2 = 1:length(iVect2)
                iLegend(j2)= int2str(iVect2(j2));
                for i2 = 1:length(gapVect2)
                    HFio2 = ThickCoil_Fiorillo(app,0,iVect2(j2),n,L,R1,R2);
                    if (HFio2 >= 100 || HFio2 <= -100)
                        BVect2(i2,j2) = MagCirc_Furlani(app,0,sign(HFio2)*100*L,mu_r,n,Ag,Ac,gapVect2(i2),lc);
                    else
                        BVect2(i2,j2) = MagCirc_Furlani(app,0,HFio2*L,mu_r,n,Ag,Ac,gapVect2(i2),lc);
                    end
                end
            end
            plot(app.GapDistPlot, gapVect2*1000,BVect2*1000)
            title(app.GapDistPlot, 'B-Field, Air Gap Length & Source Current')
            legend(app.GapDistPlot, [num2str((iVect2*1000)'),aa2])
            xlabel(app.GapDistPlot, 'Air Gap Length [mm]'), 
            ylabel(app.GapDistPlot, 'Magnetic Flux Density B [mT]')
        end

        % Value changed function: AWGDropDown_2
        function AWGDropDown_2ValueChanged(app, event)
            %value = app.AWGDropDown_2.Value;
%             mode = str2double(app.CoilVariableDropDown_2.Value);
            AWG_index = str2double(app.AWGDropDown_2.Value);
            
            app.WireDiametermmEditField_2.Value = app.AWG_diameter(AWG_index);
            app.MaximumCurrentAEditField_2.Value = app.AWG_CurrentLimit(AWG_index);
            app.TurnsEditField_2.Value = ((app.OutterRadiusmmEditField_2.Value-app.InnerRadiusmmEditField_2.Value)*app.CoilLengthmmEditField_2.Value)/(app.WireDiametermmEditField_2.Value^2);
            app.ResistanceEditField_2.Value = 2*pi*(app.OutterRadiusmmEditField_2.Value/1000)*app.TurnsEditField_2.Value*app.AWG_Ohms(AWG_index);
            
%             R2 = (app.OutterRadiusmmEditField_2.Value)/1000;
%             R1 = (app.InnerRadiusmmEditField_2.Value)/1000;
%             L = (app.CoilLengthmmEditField_2.Value)/1000;
%             n = app.TurnsEditField_2.Value;
            
%             if(mode == 0)
%                 X = app.SourceCurrentmAEditField_2.Value;
%                 app.HFieldAmEditField_2.Value = ThickCoil_Fiorillo(app,mode,X,n,L,R1,R2);
%                 
%             elseif(mode == 1)
%                 X = app.HFieldAmEditField_2.Value;
%                 app.SourceCurrentmAEditField_2.Value = ThickCoil_Fiorillo(app,mode,X,n,L,R1,R2);
%             end
            
        end

        % Value changed function: CoreMaterialDropDown_2
        function CoreMaterialDropDown_2ValueChanged(app, event)
            %value = app.CoreMaterialDropDown_2.Value;
            Core_index = str2double(app.CoreMaterialDropDown_2.Value);
%             mode_d = str2double(app.DesiredVariableDropDown_2.Value);
            
            app.CorePermeabilityrEditField_2.Value = app.Core_Permeability(Core_index);
            app.SaturationHAmEditField_2.Value = app.Core_Coercivity(Core_index);
            app.SaturationBmTEditField_2.Value = app.Core_Saturation_induction(Core_index);
            
%             lg = app.GapLengthmmEditField.Value;
%             mu_r = app.CorePermeabilityrEditField_2.Value;
%             n = app.TurnsEditField_2.Value;
%             lc = app.CoreLengthBmmEditField.Value/1000;
%             wc = app.CoreWidthmmEditField_2.Value/1000;
%             wg = app.GapWidthmmEditField_2.Value/1000;
%             tc = app.CoreThicknessmmEditField_2.Value/1000;
%             tg = app.CoreLengthYmmEditField.Value/1000;
%             Ag = wg*tg;
%             Ac = wc*tc;
%             
%             if(mode_d == 0)
%                 X = app.DesiredCurrentmAEditField_2.Value;
%                 app.DesiredBFieldmTEditField_2.Value = MagCirc_Furlani(app,mode_d,X,mu_r,n,Ag,Ac,lg,lc);    
%             elseif(mode_d == 1)
%                 X = app.DesiredBFieldmTEditField_2.Value;
%                 app.DesiredCurrentmAEditField_2.Value = MagCirc_Furlani(app,mode_d,X,mu_r,n,Ag,Ac,lg,lc);
%             end
            
        end

        % Value changed function: CoilVariableDropDown_2
        function CoilVariableDropDown_2ValueChanged(app, event)
            Coil_index = str2double(app.CoilVariableDropDown_2.Value);
            if(Coil_index == 0)
                app.SourceCurrentmAEditField_2.Editable = 'on';
                app.SourceCurrentmAEditField_2.Enable = 'on';
                app.HFieldAmEditField_2.Editable = 'off';
                app.HFieldAmEditField_2.Enable = 'off';
            elseif(Coil_index == 1) 
                app.SourceCurrentmAEditField_2.Editable = 'off';
                app.SourceCurrentmAEditField_2.Enable = 'off';
                app.HFieldAmEditField_2.Editable = 'on';
                app.HFieldAmEditField_2.Enable = 'on';
            end
        end

        % Value changed function: DesiredVariableDropDown_2
        function DesiredVariableDropDown_2ValueChanged(app, event)
            Design_index = str2double(app.DesiredVariableDropDown_2.Value);
            if(Design_index == 0)
                app.DesiredCurrentmAEditField_2.Editable = 'on';
                app.DesiredCurrentmAEditField_2.Enable = 'on';
                app.DesiredBFieldmTEditField_2.Editable = 'off';
                app.DesiredBFieldmTEditField_2.Enable = 'off';
            elseif(Design_index == 1) 
                app.DesiredCurrentmAEditField_2.Editable = 'off';
                app.DesiredCurrentmAEditField_2.Enable = 'off';
                app.DesiredBFieldmTEditField_2.Editable = 'on';
                app.DesiredBFieldmTEditField_2.Enable = 'on';
            end
        end

        % Button pushed function: LoadDesignButton_2
        function LoadDesignButton_2Pushed(app, event)
            mode = 0;
            mode_d = str2double(app.DesiredVariableDropDown_2.Value);
            LCoil = (app.CoilLengthmmEditField_2.Value)/1000;
            R1Coil = (app.InnerRadiusmmEditField_2.Value)/1000;
            R2Coil = (app.OutterRadiusmmEditField_2.Value)/1000;
            nTurns = app.TurnsEditField_2.Value;
            ThCore = (app.CoreThicknessmmEditField_2.Value)/1000;
            WdCore = (app.CoreWidthmmEditField_2.Value)/1000;
            WdGap = (app.GapWidthmmEditField_2.Value)/1000;
            LCore1 = (app.CoreLengthBmmEditField.Value)/1000;
            LCore2 = (app.CoreLengthRmmEditField.Value)/1000;
            LCoreG = (app.CoreLengthYmmEditField.Value)/1000;
            LGap = app.GapLengthmmEditField.Value/1000;
            mu_r = app.CorePermeabilityrEditField_2.Value;
            BSat = app.SaturationBmTEditField_2.Value/1000;
            Sat_Current = MagCirc_Final(app,1,BSat,LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,0,mu_r);
            app.SaturationImAEditField.Value = Sat_Current*1000;
%             %Fiorillo ThickCoil
%             %mode = str2double(app.CoilVariableDropDown.Value);
%             R2 = (app.OutterRadiusmmEditField_2.Value)/1000;
%             R1 = (app.InnerRadiusmmEditField_2.Value)/1000;
%             L = (app.CoilLengthmmEditField_2.Value)/1000;
%             %n = app.TurnsEditField_2.Value;
%             %Furlani Mag Circuit
%             iF = app.DesiredCurrentmAEditField_2.Value/1000;
%             lg = app.GapLengthmmEditField.Value/1000;
%             mu_r = app.CorePermeabilityrEditField_2.Value;
%             n = app.TurnsEditField_2.Value;
%             lc = app.CoreLengthBmmEditField.Value/1000;
%             wc = app.CoreWidthmmEditField_2.Value/1000;
%             wg = app.GapWidthmmEditField_2.Value/1000;
%             tc = app.CoreThicknessmmEditField_2.Value/1000;
%             tg = app.CoreLengthYmmEditField.Value/1000;
%             Ag = wg*tg;
%             Ac = wc*tc;
%             mu_0 = 1.25e-6;
%             mode_d = str2double(app.DesiredVariableDropDown_2.Value);

            if(mode_d == 0)
                X = app.DesiredCurrentmAEditField_2.Value/1000;
                app.DesiredBFieldmTEditField_2.Value = MagCirc_Final(app,mode_d,X,LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,LGap,mu_r)*1000;
                
            elseif(mode_d == 1)
                X = app.DesiredBFieldmTEditField_2.Value/1000;
                app.DesiredCurrentmAEditField_2.Value = MagCirc_Final(app,mode_d,X,LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,LGap,mu_r)*1000;
            end
            
            iF = (app.DesiredCurrentmAEditField_2.Value)/1000;
%             if(mode_d == 0)
%                 X = app.DesiredCurrentmAEditField_2.Value/1000;
%                 HFio = ThickCoil_Fiorillo(app,0,X,n,L,R1,R2);
%                 if (HFio >= 100 || HFio <= -100)
%                     app.DesiredBFieldmTEditField_2.Value = MagCirc_Furlani(app,mode_d,sign(HFio)*100*L,mu_r,n,Ag,Ac,lg,lc)*1000;
%                 else
%                     app.DesiredBFieldmTEditField_2.Value = MagCirc_Furlani(app,mode_d,HFio*L,mu_r,n,Ag,Ac,lg,lc)*1000;
%                 end
%             elseif(mode_d == 1)
%                 X = app.DesiredBFieldmTEditField_2.Value/1000;
%                 IFur = MagCirc_Furlani(app,mode_d,X,mu_r,1,Ag,Ac,lg,lc);
%                 app.DesiredCurrentmAEditField_2.Value = ThickCoil_Fiorillo(app,mode_d,IFur/L,n,L,R1,R2)*1000;
%             end
            
           
%             gapVect = (lg/2:lg/2:2*lg);
%             iVect = (-iF:iF/9:iF);
%             gLegend = zeros(length(iVect),1);
%             BVect = zeros(length(iVect),length(gapVect));
%             aa = [' [mm]';' [mm]';' [mm]';' [mm]'];
%             for j = 1:length(gapVect)
%                 gLegend(j)= int2str(gapVect(j));
%                 for i = 1:length(iVect)
%                     HFio = ThickCoil_Fiorillo(app,0,iVect(i),n,L,R1,R2);
%                     if (HFio >= 100 || HFio <= -100)
%                         BVect(i,j) = MagCirc_Furlani(app,0,sign(HFio)*100*L,mu_r,n,Ag,Ac,gapVect(j),lc);
%                     else
%                         BVect(i,j) = MagCirc_Furlani(app,0,HFio*L,mu_r,n,Ag,Ac,gapVect(j),lc);
%                     end
%                 end
%             end
            gapVect = (LGap/2:LGap/2:2*LGap);
            iVect = (-iF:iF/9:iF);
            gLegend = zeros(length(iVect),1);
            BVect = zeros(length(iVect),length(gapVect));
            aa = [' [mm]';' [mm]';' [mm]';' [mm]'];
            for j = 1:length(gapVect)
                gLegend(j)= int2str(gapVect(j));
                for i = 1:length(iVect)
                    if (iVect(i) <= -Sat_Current  || iVect(i) >= Sat_Current)
                        BVect(i,j) = MagCirc_Final(app,mode,Sat_Current*sign(iVect(i)),LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,gapVect(j),mu_r);
                    else
                        BVect(i,j) = MagCirc_Final(app,mode,iVect(i),LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,gapVect(j),mu_r);
                    end
                end
            end
            plot(app.CircuitResponsePlot_2,iVect*1000,BVect*1000);
            title(app.CircuitResponsePlot_2,'B-Field, Air Gap Length & Source Current');
            lg1 = legend(app.CircuitResponsePlot_2,[num2str((gapVect*1000)'),aa]); 
            lg1.Location = 'southeast';
            xlabel(app.CircuitResponsePlot_2,'Source Current [mA]');
            ylabel(app.CircuitResponsePlot_2,'Magnetic Flux Density B [mT]');
            
%             gapVect2 = (lg/10:lg/10:lg*4);
%             iVect2 = (iF/4:iF/4:iF);
%             iLegend = zeros(length(iVect2),1);
%             BVect2 = zeros(length(gapVect2),length(iVect2));
%             aa2 = [' [mA]';' [mA]';' [mA]';' [mA]'];
%             for j2 = 1:length(iVect2)
%                 iLegend(j2)= int2str(iVect2(j2));
%                 for i2 = 1:length(gapVect2)
%                     HFio2 = ThickCoil_Fiorillo(app,0,iVect2(j2),n,L,R1,R2);
%                     if (HFio2 >= 100 || HFio2 <= -100)
%                         BVect2(i2,j2) = MagCirc_Furlani(app,0,sign(HFio2)*100*L,mu_r,n,Ag,Ac,gapVect2(i2),lc);
%                     else
%                         BVect2(i2,j2) = MagCirc_Furlani(app,0,HFio2*L,mu_r,n,Ag,Ac,gapVect2(i2),lc);
%                     end
%                 end
%             end
            gapVect2 = (LGap/10:LGap/10:LGap*4);
            iVect2 = (iF/4:iF/4:iF);
            iLegend = zeros(length(iVect2),1);
            BVect2 = zeros(length(gapVect2),length(iVect2));
            aa2 = [' [mA]';' [mA]';' [mA]';' [mA]'];
            for j2 = 1:length(iVect2)
                iLegend(j2)= int2str(iVect2(j2));
                for i2 = 1:length(gapVect2)
                    if (iVect2(j2) <= -Sat_Current  || iVect2(j2) >= Sat_Current)
                        BVect2(i2,j2) = MagCirc_Final(app,mode,Sat_Current*sign(iVect2(j2)),LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,gapVect2(i2),mu_r);
                    else
                        BVect2(i2,j2) = MagCirc_Final(app,mode,iVect2(j2),LCoil,R1Coil,R2Coil,nTurns,ThCore,WdCore,WdGap,LCore1,LCore2,LCoreG,gapVect2(i2),mu_r);
                    end
                end
            end
            plot(app.GapDistPlot_2, gapVect2*1000,BVect2*1000)
            title(app.GapDistPlot_2, 'B-Field, Air Gap Length & Source Current')
            legend(app.GapDistPlot_2, [num2str((iVect2*1000)'),aa2])
            xlabel(app.GapDistPlot_2, 'Air Gap Length [mm]'), 
            ylabel(app.GapDistPlot_2, 'Magnetic Flux Density B [mT]')
        end

        % Button pushed function: LoadCoilButton_2
        function LoadCoilButton_2Pushed(app, event)
            mode = str2double(app.CoilVariableDropDown_2.Value);
            R2 = (app.OutterRadiusmmEditField_2.Value)/1000;
            R1 = (app.InnerRadiusmmEditField_2.Value)/1000;
            L = (app.CoilLengthmmEditField_2.Value)/1000;
            n = app.TurnsEditField_2.Value;
            
            if(mode == 0)
                X = app.SourceCurrentmAEditField_2.Value/1000;
                app.HFieldAmEditField_2.Value = ThickCoil_Fiorillo(app,mode,X,n,L,R1,R2);
                
            elseif(mode == 1)
                X = app.HFieldAmEditField_2.Value;
                app.SourceCurrentmAEditField_2.Value = ThickCoil_Fiorillo(app,mode,X,n,L,R1,R2)*1000;
            end
        end

        % Value changed function: CoilLengthmmEditField_2, 
        % ...and 2 other components
        function InnerRadiusmmEditField_2ValueChanged(app, event)
            AWG_index = str2double(app.AWGDropDown_2.Value);
            
            app.TurnsEditField_2.Value = ((app.OutterRadiusmmEditField_2.Value-app.InnerRadiusmmEditField_2.Value)*app.CoilLengthmmEditField_2.Value)/(app.WireDiametermmEditField_2.Value^2);
            app.ResistanceEditField_2.Value = 2*pi*(app.OutterRadiusmmEditField_2.Value/1000)*app.TurnsEditField_2.Value*app.AWG_Ohms(AWG_index);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagneticCircuitCalculatorUIFigure = uifigure('Visible', 'off');
            app.MagneticCircuitCalculatorUIFigure.Name = 'Magnetic Circuit Calculator';
            app.TabGroup = uitabgroup(app.MagneticCircuitCalculatorUIFigure);
            app.GuideModeTab = uitab(app.TabGroup);
            app.GuideModeTab.Title = 'Guide Mode';
            app.GapDistPlot = uiaxes(app.GuideModeTab);
            title(app.GapDistPlot, 'B-Field, Air Gap Length & Source Current')
            xlabel(app.GapDistPlot, 'Gap Length [mm]')
            ylabel(app.GapDistPlot, 'Magnetic Flux Density B [mT]')
            app.CircuitResponsePlot = uiaxes(app.GuideModeTab);
            title(app.CircuitResponsePlot, 'B-Field, Air Gap Length & Source Current')
            xlabel(app.CircuitResponsePlot, 'Source Current [mA]')
            ylabel(app.CircuitResponsePlot, 'Magnetic Flux Density B [mT]')
            app.CoreGuide = uiaxes(app.GuideModeTab);
            title(app.CoreGuide, 'Electromagnet C type core')
            xlabel(app.CoreGuide, 'Example width [mm]')
            ylabel(app.CoreGuide, 'Example length [mm]')
            app.CoreCurves = uiaxes(app.GuideModeTab);
            title(app.CoreCurves, 'B-Field Curves of Solid Material')
            xlabel(app.CoreCurves, 'Field Strength [kA/m]')
            ylabel(app.CoreCurves, 'Flux Density [T]')
            app.MagneticCircuitCharacteristicsPanel = uipanel(app.GuideModeTab);
            app.MagneticCircuitCharacteristicsPanel.Title = 'Magnetic Circuit Characteristics';
            app.CoilCharacteristicsPanel = uipanel(app.MagneticCircuitCharacteristicsPanel);
            app.CoilCharacteristicsPanel.Title = 'Coil Characteristics';
            app.InnerRadiusmmEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.InnerRadiusmmEditFieldLabel.Text = 'Inner Radius [mm]';
            app.InnerRadiusmmEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.InnerRadiusmmEditField.Editable = 'off';
            app.InnerRadiusmmEditField.Enable = 'off';
            app.InnerRadiusmmEditField.Value = 5;
            app.OutterRadiusmmEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.OutterRadiusmmEditFieldLabel.Text = 'Outter Radius [mm]';
            app.OutterRadiusmmEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.OutterRadiusmmEditField.Editable = 'off';
            app.OutterRadiusmmEditField.Enable = 'off';
            app.OutterRadiusmmEditField.Value = 9;
            app.CoilLengthmmEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.CoilLengthmmEditFieldLabel.Text = 'Coil Length [mm]';
            app.CoilLengthmmEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.CoilLengthmmEditField.Editable = 'off';
            app.CoilLengthmmEditField.Enable = 'off';
            app.CoilLengthmmEditField.Value = 18;
            app.AWGDropDownLabel = uilabel(app.CoilCharacteristicsPanel);
            app.AWGDropDownLabel.Text = 'AWG';
            app.AWGDropDown = uidropdown(app.CoilCharacteristicsPanel);
            app.AWGDropDown.Items = {'OOOO', 'OOO', 'OO', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', 'Metric 2.0', '33', 'Metric 1.8', '34', 'Metric 1.6', '35', 'Metric 1.4', '36', 'Metric 1.25', '37', 'Metric 1.12', '38', 'Metric 1', '39', '40'};
            app.AWGDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51'};
            app.AWGDropDown.Enable = 'off';
            app.AWGDropDown.Value = '26';
            app.TurnsEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.TurnsEditFieldLabel.Text = '# Turns';
            app.TurnsEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.TurnsEditField.Editable = 'off';
            app.TurnsEditField.Enable = 'off';
            app.TurnsEditField.Value = 173;
            app.HFieldAmLabel = uilabel(app.CoilCharacteristicsPanel);
            app.HFieldAmLabel.Text = 'H-Field [A/m]';
            app.HFieldAmEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.HFieldAmEditField.Editable = 'off';
            app.HFieldAmEditField.Enable = 'off';
            app.HFieldAmEditField.Value = 0.0624;
            app.MaximumCurrentAEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.MaximumCurrentAEditFieldLabel.Text = 'Maximum Current [A]';
            app.MaximumCurrentAEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.MaximumCurrentAEditField.ValueDisplayFormat = '%.2f';
            app.MaximumCurrentAEditField.Editable = 'off';
            app.MaximumCurrentAEditField.Enable = 'off';
            app.MaximumCurrentAEditField.Value = 0.92;
            app.WireDiametermmEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.WireDiametermmEditFieldLabel.Text = 'Wire Diameter [mm]';
            app.WireDiametermmEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.WireDiametermmEditField.Editable = 'off';
            app.WireDiametermmEditField.Enable = 'off';
            app.WireDiametermmEditField.Value = 0.6452;
            app.ResistanceLabel = uilabel(app.CoilCharacteristicsPanel);
            app.ResistanceLabel.Text = 'Resistance [Ω]';
            app.ResistanceEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.ResistanceEditField.Editable = 'off';
            app.ResistanceEditField.Enable = 'off';
            app.ResistanceEditField.Value = 56.5487;
            app.SourceCurrentmAEditFieldLabel = uilabel(app.CoilCharacteristicsPanel);
            app.SourceCurrentmAEditFieldLabel.Text = 'Source Current [mA]';
            app.SourceCurrentmAEditField = uieditfield(app.CoilCharacteristicsPanel, 'numeric');
            app.SourceCurrentmAEditField.Editable = 'off';
            app.SourceCurrentmAEditField.Enable = 'off';
            app.SourceCurrentmAEditField.Value = 0.0624;
            app.LoadDesignButton = uibutton(app.MagneticCircuitCharacteristicsPanel, 'push');
            app.LoadDesignButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDesignButtonPushed, true);
            app.LoadDesignButton.Text = 'Load Design';
            app.LoadCoilButton = uibutton(app.MagneticCircuitCharacteristicsPanel, 'push');
            app.LoadCoilButton.Enable = 'off';
            app.LoadCoilButton.Text = 'Load Coil';
            app.CoreCharacteristicsPanel = uipanel(app.MagneticCircuitCharacteristicsPanel);
            app.CoreCharacteristicsPanel.Title = 'Core Characteristics';
            app.CoreMaterialDropDownLabel = uilabel(app.CoreCharacteristicsPanel);
            app.CoreMaterialDropDownLabel.Text = 'Core Material';
            app.CoreMaterialDropDown = uidropdown(app.CoreCharacteristicsPanel);
            app.CoreMaterialDropDown.Items = {'Iron', 'MuMetal', 'VACOFLUX 48', 'VACOPERM 100', 'ULTRAVAC 80'};
            app.CoreMaterialDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.CoreMaterialDropDown.Enable = 'off';
            app.CoreMaterialDropDown.Value = '1';
            app.CorePermeabilityrEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.CorePermeabilityrEditFieldLabel.Text = 'Core Permeability μr';
            app.CorePermeabilityrEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CorePermeabilityrEditField.ValueDisplayFormat = '%.0f';
            app.CorePermeabilityrEditField.Editable = 'off';
            app.CorePermeabilityrEditField.Enable = 'off';
            app.CorePermeabilityrEditField.Value = 1000;
            app.CoreWidthmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.CoreWidthmmEditFieldLabel.Text = 'Core Width [mm]';
            app.CoreWidthmmEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CoreWidthmmEditField.Editable = 'off';
            app.CoreWidthmmEditField.Enable = 'off';
            app.CoreWidthmmEditField.Value = 31.5;
            app.CoreThicknessmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.CoreThicknessmmEditFieldLabel.Text = 'Core Thickness [mm]';
            app.CoreThicknessmmEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CoreThicknessmmEditField.Editable = 'off';
            app.CoreThicknessmmEditField.Enable = 'off';
            app.CoreThicknessmmEditField.Value = 10;
            app.CoreLengthBmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel);
            app.CoreLengthBmmEditField_2Label.Text = 'Core Length (B)[mm]';
            app.CoreLengthBmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CoreLengthBmmEditField_2.Editable = 'off';
            app.CoreLengthBmmEditField_2.Enable = 'off';
            app.CoreLengthBmmEditField_2.Value = 61;
            app.GapWidthmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.GapWidthmmEditFieldLabel.Text = 'Gap Width [mm]';
            app.GapWidthmmEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.GapWidthmmEditField.Editable = 'off';
            app.GapWidthmmEditField.Enable = 'off';
            app.GapWidthmmEditField.Value = 2;
            app.CoreLengthYmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel);
            app.CoreLengthYmmEditField_2Label.Text = 'Core Length (Y)[mm]';
            app.CoreLengthYmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CoreLengthYmmEditField_2.Editable = 'off';
            app.CoreLengthYmmEditField_2.Enable = 'off';
            app.CoreLengthYmmEditField_2.Value = 10;
            app.GapLenghtmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.GapLenghtmmEditFieldLabel.Text = 'Gap Lenght [mm]';
            app.GapLenghtmmEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.GapLenghtmmEditField.Editable = 'off';
            app.GapLenghtmmEditField.Enable = 'off';
            app.GapLenghtmmEditField.Value = 2;
            app.DesiredBFieldmTEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.DesiredBFieldmTEditFieldLabel.Text = 'Desired B-Field [mT]';
            app.DesiredBFieldmTEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.DesiredBFieldmTEditField.Editable = 'off';
            app.DesiredBFieldmTEditField.Enable = 'off';
            app.DesiredBFieldmTEditField.Value = 200;
            app.DesiredCurrentmAEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.DesiredCurrentmAEditFieldLabel.Text = 'Desired Current [mA]';
            app.DesiredCurrentmAEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.DesiredCurrentmAEditField.Editable = 'off';
            app.DesiredCurrentmAEditField.Enable = 'off';
            app.DesiredCurrentmAEditField.Value = 30;
            app.SaturationHAmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.SaturationHAmEditFieldLabel.Text = 'Saturation H [A/m]';
            app.SaturationHAmEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.SaturationHAmEditField.Editable = 'off';
            app.SaturationHAmEditField.Enable = 'off';
            app.SaturationHAmEditField.Value = 0.0624;
            app.SaturationBmTEditFieldLabel = uilabel(app.CoreCharacteristicsPanel);
            app.SaturationBmTEditFieldLabel.Text = 'Saturation B [mT]';
            app.SaturationBmTEditField = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.SaturationBmTEditField.Editable = 'off';
            app.SaturationBmTEditField.Enable = 'off';
            app.SaturationBmTEditField.Value = 740;
            app.CoreLengthRmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel);
            app.CoreLengthRmmEditField_2Label.Text = 'Core Length (R)[mm]';
            app.CoreLengthRmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.CoreLengthRmmEditField_2.Editable = 'off';
            app.CoreLengthRmmEditField_2.Enable = 'off';
            app.CoreLengthRmmEditField_2.Value = 61;
            app.SaturationImAEditField_2Label = uilabel(app.CoreCharacteristicsPanel);
            app.SaturationImAEditField_2Label.Text = 'Saturation I [mA]';
            app.SaturationImAEditField_2 = uieditfield(app.CoreCharacteristicsPanel, 'numeric');
            app.SaturationImAEditField_2.ValueDisplayFormat = '%.2f';
            app.SaturationImAEditField_2.Editable = 'off';
            app.SaturationImAEditField_2.Enable = 'off';
            app.SaturationImAEditField_2.Value = 100;
            app.DesiredVariableDropDownLabel = uilabel(app.MagneticCircuitCharacteristicsPanel);
            app.DesiredVariableDropDownLabel.Text = 'Desired Variable';
            app.DesiredVariableDropDown = uidropdown(app.MagneticCircuitCharacteristicsPanel);
            app.DesiredVariableDropDown.Items = {'B-Field [mT]', 'Current [mA]'};
            app.DesiredVariableDropDown.Enable = 'off';
            app.DesiredVariableDropDown.Value = 'B-Field [mT]';
            app.CoilVariableDropDownLabel = uilabel(app.MagneticCircuitCharacteristicsPanel);
            app.CoilVariableDropDownLabel.Text = 'Coil Variable';
            app.CoilVariableDropDown = uidropdown(app.MagneticCircuitCharacteristicsPanel);
            app.CoilVariableDropDown.Items = {'H-Field [A/m]', 'Current [mA]'};
            app.CoilVariableDropDown.Enable = 'off';
            app.CoilVariableDropDown.Value = 'H-Field [A/m]';
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.GapDistPlot_2 = uiaxes(app.CalculatorTab);
            title(app.GapDistPlot_2, 'B-Field, Air Gap Length & Source Current')
            xlabel(app.GapDistPlot_2, 'Gap Length [mm]')
            ylabel(app.GapDistPlot_2, 'Magnetic Flux Density B [mT]')
            app.CircuitResponsePlot_2 = uiaxes(app.CalculatorTab);
            title(app.CircuitResponsePlot_2, 'B-Field, Air Gap Length & Source Current')
            xlabel(app.CircuitResponsePlot_2, 'Source Current [mA]')
            ylabel(app.CircuitResponsePlot_2, 'Magnetic Flux Density B [mT]')
            app.CoreGuide_2 = uiaxes(app.CalculatorTab);
            title(app.CoreGuide_2, 'Electromagnet C type core')
            xlabel(app.CoreGuide_2, 'Example width [mm]')
            ylabel(app.CoreGuide_2, 'Example length [mm]')
            app.CoreCurves_2 = uiaxes(app.CalculatorTab);
            title(app.CoreCurves_2, 'B-Field Curves of Solid Material')
            xlabel(app.CoreCurves_2, 'Field Strength [kA/m]')
            ylabel(app.CoreCurves_2, 'Flux Density [T]')
            app.MagneticCircuitCharacteristicsPanel_2 = uipanel(app.CalculatorTab);
            app.MagneticCircuitCharacteristicsPanel_2.Title = 'Magnetic Circuit Characteristics';
            app.CoilCharacteristicsPanel_2 = uipanel(app.MagneticCircuitCharacteristicsPanel_2);
            app.CoilCharacteristicsPanel_2.Title = 'Coil Characteristics';
            app.InnerRadiusmmEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.InnerRadiusmmEditField_2Label.Text = 'Inner Radius [mm]';
            app.InnerRadiusmmEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.InnerRadiusmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @InnerRadiusmmEditField_2ValueChanged, true);
            app.InnerRadiusmmEditField_2.Value = 5;
            app.OutterRadiusmmEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.OutterRadiusmmEditField_2Label.Text = 'Outter Radius [mm]';
            app.OutterRadiusmmEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.OutterRadiusmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @InnerRadiusmmEditField_2ValueChanged, true);
            app.OutterRadiusmmEditField_2.Value = 9;
            app.CoilLengthmmEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.CoilLengthmmEditField_2Label.Text = 'Coil Length [mm]';
            app.CoilLengthmmEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.CoilLengthmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @InnerRadiusmmEditField_2ValueChanged, true);
            app.CoilLengthmmEditField_2.Value = 18;
            app.AWGDropDown_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.AWGDropDown_2Label.Text = 'AWG';
            app.AWGDropDown_2 = uidropdown(app.CoilCharacteristicsPanel_2);
            app.AWGDropDown_2.Items = {'OOOO', 'OOO', 'OO', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', 'Metric 2.0', '33', 'Metric 1.8', '34', 'Metric 1.6', '35', 'Metric 1.4', '36', 'Metric 1.25', '37', 'Metric 1.12', '38', 'Metric 1', '39', '40'};
            app.AWGDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51'};
            app.AWGDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AWGDropDown_2ValueChanged, true);
            app.AWGDropDown_2.Value = '1';
            app.TurnsEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.TurnsEditField_2Label.Text = '# Turns';
            app.TurnsEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.TurnsEditField_2.ValueDisplayFormat = '%.0f';
            app.TurnsEditField_2.Editable = 'off';
            app.TurnsEditField_2.Value = 1000;
            app.HFieldAmEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.HFieldAmEditField_2Label.Text = 'H-Field [A/m]';
            app.HFieldAmEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.HFieldAmEditField_2.ValueDisplayFormat = '%.4f';
            app.HFieldAmEditField_2.Value = 0.0624;
            app.MaximumCurrentAEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.MaximumCurrentAEditField_2Label.Text = 'Maximum Current [A]';
            app.MaximumCurrentAEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.MaximumCurrentAEditField_2.ValueDisplayFormat = '%.4f';
            app.MaximumCurrentAEditField_2.Editable = 'off';
            app.MaximumCurrentAEditField_2.Value = 0.92;
            app.WireDiametermmEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.WireDiametermmEditField_2Label.Text = 'Wire Diameter [mm]';
            app.WireDiametermmEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.WireDiametermmEditField_2.ValueDisplayFormat = '%.4f';
            app.WireDiametermmEditField_2.Editable = 'off';
            app.WireDiametermmEditField_2.Value = 0.6452;
            app.ResistanceEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.ResistanceEditField_2Label.Text = 'Resistance [Ω]';
            app.ResistanceEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.ResistanceEditField_2.ValueDisplayFormat = '%.4f';
            app.ResistanceEditField_2.Editable = 'off';
            app.ResistanceEditField_2.Value = 56.5487;
            app.SourceCurrentmAEditField_2Label = uilabel(app.CoilCharacteristicsPanel_2);
            app.SourceCurrentmAEditField_2Label.Text = 'Source Current [mA]';
            app.SourceCurrentmAEditField_2 = uieditfield(app.CoilCharacteristicsPanel_2, 'numeric');
            app.SourceCurrentmAEditField_2.ValueDisplayFormat = '%.4f';
            app.SourceCurrentmAEditField_2.Editable = 'off';
            app.SourceCurrentmAEditField_2.Enable = 'off';
            app.SourceCurrentmAEditField_2.Value = 300;
            app.LoadDesignButton_2 = uibutton(app.MagneticCircuitCharacteristicsPanel_2, 'push');
            app.LoadDesignButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDesignButton_2Pushed, true);
            app.LoadDesignButton_2.Text = 'Load Design';
            app.LoadCoilButton_2 = uibutton(app.MagneticCircuitCharacteristicsPanel_2, 'push');
            app.LoadCoilButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilButton_2Pushed, true);
            app.LoadCoilButton_2.Text = 'Load Coil';
            app.CoreCharacteristicsPanel_2 = uipanel(app.MagneticCircuitCharacteristicsPanel_2);
            app.CoreCharacteristicsPanel_2.Title = 'Core Characteristics';
            app.CoreMaterialDropDown_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreMaterialDropDown_2Label.Text = 'Core Material';
            app.CoreMaterialDropDown_2 = uidropdown(app.CoreCharacteristicsPanel_2);
            app.CoreMaterialDropDown_2.Items = {'Iron', 'MuMetal', 'VACOFLUX 48', 'VACOPERM 100', 'ULTRAVAC 80'};
            app.CoreMaterialDropDown_2.ItemsData = {'1', '2', '3', '4', '5'};
            app.CoreMaterialDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CoreMaterialDropDown_2ValueChanged, true);
            app.CoreMaterialDropDown_2.Value = '1';
            app.CorePermeabilityrEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.CorePermeabilityrEditField_2Label.Text = 'Core Permeability μr';
            app.CorePermeabilityrEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CorePermeabilityrEditField_2.ValueDisplayFormat = '%.0f';
            app.CorePermeabilityrEditField_2.Editable = 'off';
            app.CorePermeabilityrEditField_2.Value = 1000;
            app.CoreWidthmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreWidthmmEditField_2Label.Text = 'Core Width [mm]';
            app.CoreWidthmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CoreWidthmmEditField_2.Value = 31.5;
            app.CoreThicknessmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreThicknessmmEditField_2Label.Text = 'Core Thickness [mm]';
            app.CoreThicknessmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CoreThicknessmmEditField_2.Value = 10;
            app.CoreLengthBmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreLengthBmmEditFieldLabel.Text = 'Core Length (B)[mm]';
            app.CoreLengthBmmEditField = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CoreLengthBmmEditField.Value = 61;
            app.GapWidthmmEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.GapWidthmmEditField_2Label.Text = 'Gap Width [mm]';
            app.GapWidthmmEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.GapWidthmmEditField_2.Value = 2;
            app.CoreLengthYmmLabel = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreLengthYmmLabel.Text = 'Core Length (Y)[mm]';
            app.CoreLengthYmmEditField = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CoreLengthYmmEditField.Value = 10;
            app.GapLengthmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel_2);
            app.GapLengthmmEditFieldLabel.Text = 'Gap Length [mm]';
            app.GapLengthmmEditField = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.GapLengthmmEditField.Value = 2;
            app.DesiredBFieldmTEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.DesiredBFieldmTEditField_2Label.Text = 'Desired B-Field [mT]';
            app.DesiredBFieldmTEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.DesiredBFieldmTEditField_2.ValueDisplayFormat = '%.2f';
            app.DesiredBFieldmTEditField_2.Value = 200;
            app.DesiredCurrentmAEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.DesiredCurrentmAEditField_2Label.Text = 'Desired Current [mA]';
            app.DesiredCurrentmAEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.DesiredCurrentmAEditField_2.ValueDisplayFormat = '%.4f';
            app.DesiredCurrentmAEditField_2.Editable = 'off';
            app.DesiredCurrentmAEditField_2.Enable = 'off';
            app.DesiredCurrentmAEditField_2.Value = 300;
            app.SaturationHAmEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.SaturationHAmEditField_2Label.Text = 'Saturation H [A/m]';
            app.SaturationHAmEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.SaturationHAmEditField_2.ValueDisplayFormat = '%.4f';
            app.SaturationHAmEditField_2.Editable = 'off';
            app.SaturationHAmEditField_2.Value = 0.0624;
            app.SaturationBmTEditField_2Label = uilabel(app.CoreCharacteristicsPanel_2);
            app.SaturationBmTEditField_2Label.Text = 'Saturation B [mT]';
            app.SaturationBmTEditField_2 = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.SaturationBmTEditField_2.ValueDisplayFormat = '%.2f';
            app.SaturationBmTEditField_2.Editable = 'off';
            app.SaturationBmTEditField_2.Value = 740;
            app.CoreLengthRmmEditFieldLabel = uilabel(app.CoreCharacteristicsPanel_2);
            app.CoreLengthRmmEditFieldLabel.Text = 'Core Length (R)[mm]';
            app.CoreLengthRmmEditField = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.CoreLengthRmmEditField.Value = 61;
            app.SaturationImAEditFieldLabel = uilabel(app.CoreCharacteristicsPanel_2);
            app.SaturationImAEditFieldLabel.Text = 'Saturation I [mA]';
            app.SaturationImAEditField = uieditfield(app.CoreCharacteristicsPanel_2, 'numeric');
            app.SaturationImAEditField.ValueDisplayFormat = '%.2f';
            app.SaturationImAEditField.Editable = 'off';
            app.SaturationImAEditField.Value = 100;
            app.DesiredVariableDropDown_2Label = uilabel(app.MagneticCircuitCharacteristicsPanel_2);
            app.DesiredVariableDropDown_2Label.Text = 'Desired Variable';
            app.DesiredVariableDropDown_2 = uidropdown(app.MagneticCircuitCharacteristicsPanel_2);
            app.DesiredVariableDropDown_2.Items = {'B-Field [mT]', 'Current [mA]'};
            app.DesiredVariableDropDown_2.ItemsData = {'1', '0'};
            app.DesiredVariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DesiredVariableDropDown_2ValueChanged, true);
            app.DesiredVariableDropDown_2.Value = '1';
            app.CoilVariableDropDown_2Label = uilabel(app.MagneticCircuitCharacteristicsPanel_2);
            app.CoilVariableDropDown_2Label.Text = 'Coil Variable';
            app.CoilVariableDropDown_2 = uidropdown(app.MagneticCircuitCharacteristicsPanel_2);
            app.CoilVariableDropDown_2.Items = {'H-Field [A/m]', 'Current [mA]'};
            app.CoilVariableDropDown_2.ItemsData = {'1', '0'};
            app.CoilVariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CoilVariableDropDown_2ValueChanged, true);
            app.CoilVariableDropDown_2.Value = '1';
            % Show the figure after all components are created
            app.MagneticCircuitCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestCalculatorV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagneticCircuitCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagneticCircuitCalculatorUIFigure)
        end
    end
end